classdef Parameter_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WelcomeUserPleaseTuneParametersBeforeSimulationStartLabel  matlab.ui.control.Label
        CreatePButton              matlab.ui.control.Button
        Ppl0Spinner                matlab.ui.control.Spinner
        Ppl0SpinnerLabel           matlab.ui.control.Label
        Flow0Spinner               matlab.ui.control.Spinner
        Flow0SpinnerLabel          matlab.ui.control.Label
        Pao0Spinner                matlab.ui.control.Spinner
        Pao0SpinnerLabel           matlab.ui.control.Label
        Palv0Spinner               matlab.ui.control.Spinner
        Palv0SpinnerLabel          matlab.ui.control.Label
        V0Spinner                  matlab.ui.control.Spinner
        V0SpinnerLabel             matlab.ui.control.Label
        InitialValuesLabel         matlab.ui.control.Label
        TdeflateSpinner            matlab.ui.control.Spinner
        TdeflateSpinnerLabel       matlab.ui.control.Label
        sim_lgthSpinner            matlab.ui.control.Spinner
        sim_lgthSpinnerLabel       matlab.ui.control.Label
        dtSpinner                  matlab.ui.control.Spinner
        dtSpinnerLabel             matlab.ui.control.Label
        SimulationParametersLabel  matlab.ui.control.Label
        CLSpinner                  matlab.ui.control.Spinner
        CLSpinnerLabel             matlab.ui.control.Label
        CrsSpinner                 matlab.ui.control.Spinner
        CrsSpinnerLabel            matlab.ui.control.Label
        CalvSpinner                matlab.ui.control.Spinner
        CalvSpinnerLabel           matlab.ui.control.Label
        RSpinner                   matlab.ui.control.Spinner
        RSpinnerLabel              matlab.ui.control.Label
        LungPropertiesLabel        matlab.ui.control.Label
        PmusCycleSpinner           matlab.ui.control.Spinner
        PmusCycleSpinnerLabel      matlab.ui.control.Label
        PmusExpLgthSpinner         matlab.ui.control.Spinner
        PmusExpLgthSpinnerLabel    matlab.ui.control.Label
        PmusSetSpinner             matlab.ui.control.Spinner
        PmusSetSpinnerLabel        matlab.ui.control.Label
        PmusPauseSpinner           matlab.ui.control.Spinner
        PmusPauseSpinnerLabel      matlab.ui.control.Label
        PmusTeSpinner              matlab.ui.control.Spinner
        PmusTeSpinnerLabel         matlab.ui.control.Label
        PmusTiSpinner              matlab.ui.control.Spinner
        PmusTiSpinnerLabel         matlab.ui.control.Label
        PmusSettingsLabel          matlab.ui.control.Label
        PSTriggerSpinner           matlab.ui.control.Spinner
        PSTriggerSpinnerLabel      matlab.ui.control.Label
        TriseSpinner               matlab.ui.control.Spinner
        TriseSpinnerLabel          matlab.ui.control.Label
        TeSpinner                  matlab.ui.control.Spinner
        TeSpinnerLabel             matlab.ui.control.Label
        TiSpinner                  matlab.ui.control.Spinner
        TiSpinnerLabel             matlab.ui.control.Label
        VentSettingsLabel          matlab.ui.control.Label
        TCTSpinner                 matlab.ui.control.Spinner
        TCTSpinnerLabel            matlab.ui.control.Label
        RRSpinner                  matlab.ui.control.Spinner
        RRSpinnerLabel             matlab.ui.control.Label
        PEEPSpinner                matlab.ui.control.Spinner
        PEEPSpinnerLabel           matlab.ui.control.Label
        PSSpinner                  matlab.ui.control.Spinner
        PSSpinnerLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: dtSpinner
        function dtSpinnerValueChanged(app, event)
            value = app.dtSpinner.Value;
            P.resp.dt = value;
        end

        % Button pushed function: CreatePButton
        function CreatePButtonPushed(app, event)
            global P;
        end

        % Value changed function: Ppl0Spinner
        function Ppl0SpinnerValueChanged(app, event)
            value = app.Ppl0Spinner.Value;
            P.resp.Ppl0 = value;
        end

        % Value changed function: Flow0Spinner
        function Flow0SpinnerValueChanged(app, event)
            value = app.Flow0Spinner.Value;
            P.resp.Flow0 = value;
        end

        % Value changed function: Pao0Spinner
        function Pao0SpinnerValueChanged(app, event)
            value = app.Pao0Spinner.Value;
            P.resp.Pao = value;
        end

        % Value changed function: Palv0Spinner
        function Palv0SpinnerValueChanged(app, event)
            value = app.Palv0Spinner.Value;
            P.resp.Palv = value;
        end

        % Value changed function: V0Spinner
        function V0SpinnerValueChanged(app, event)
            value = app.V0Spinner.Value;
            P.resp.V0 = value;
        end

        % Value changed function: TdeflateSpinner
        function TdeflateSpinnerValueChanged(app, event)
            value = app.TdeflateSpinner.Value;
            P.resp.Tdeflate = value;
        end

        % Value changed function: sim_lgthSpinner
        function sim_lgthSpinnerValueChanged(app, event)
            value = app.sim_lgthSpinner.Value;
            P.resp.sim_lgth = value;
        end

        % Value changed function: CLSpinner
        function CLSpinnerValueChanged(app, event)
            value = app.CLSpinner.Value;
            P.resp.CL = value;
        end

        % Value changed function: CrsSpinner
        function CrsSpinnerValueChanged(app, event)
            value = app.CrsSpinner.Value;
            P.resp.Crs = value;
        end

        % Value changed function: CalvSpinner
        function CalvSpinnerValueChanged(app, event)
            value = app.CalvSpinner.Value;
            P.resp.Calv = value;
        end

        % Value changed function: RSpinner
        function RSpinnerValueChanged(app, event)
            value = app.RSpinner.Value;
            P.resp.RS = value;
        end

        % Value changed function: PmusCycleSpinner
        function PmusCycleSpinnerValueChanged(app, event)
            value = app.PmusCycleSpinner.Value;
            P.resp.PmusCycle = value;
        end

        % Value changed function: PmusExpLgthSpinner
        function PmusExpLgthSpinnerValueChanged(app, event)
            value = app.PmusExpLgthSpinner.Value;
            P.resp.PmusExpLgth = value;
        end

        % Value changed function: PmusSetSpinner
        function PmusSetSpinnerValueChanged(app, event)
            value = app.PmusSetSpinner.Value;
            P.resp.PmusSet = value;
        end

        % Value changed function: PmusPauseSpinner
        function PmusPauseSpinnerValueChanged(app, event)
            value = app.PmusPauseSpinner.Value;
            P.resp.PmusPause = value;
        end

        % Value changed function: PmusTeSpinner
        function PmusTeSpinnerValueChanged(app, event)
            value = app.PmusTeSpinner.Value;
            P.resp.PmusTe = value;
        end

        % Value changed function: PmusTiSpinner
        function PmusTiSpinnerValueChanged(app, event)
            value = app.PmusTiSpinner.Value;
            P.resp.PmusTi = value;
        end

        % Value changed function: PSTriggerSpinner
        function PSTriggerSpinnerValueChanged(app, event)
            value = app.PSTriggerSpinner.Value;
            P.resp.PSTrigger = value;
        end

        % Value changed function: TriseSpinner
        function TriseSpinnerValueChanged(app, event)
            value = app.TriseSpinner.Value;
            P.resp.Trise = value;
        end

        % Value changed function: TeSpinner
        function TeSpinnerValueChanged(app, event)
            value = app.TeSpinner.Value;
            P.resp.Te = value;
        end

        % Value changed function: TiSpinner
        function TiSpinnerValueChanged(app, event)
            value = app.TiSpinner.Value;
            P.resp.Ti = value;
        end

        % Value changed function: TCTSpinner
        function TCTSpinnerValueChanged(app, event)
            value = app.TCTSpinner.Value;
            P.resp.TCT = value;
        end

        % Value changed function: RRSpinner
        function RRSpinnerValueChanged(app, event)
            value = app.RRSpinner.Value;
            P.resp.RR = value;
        end

        % Value changed function: PEEPSpinner
        function PEEPSpinnerValueChanged(app, event)
            value = app.PEEPSpinner.Value;
            P.resp.PEEP = value;
        end

        % Value changed function: PSSpinner
        function PSSpinnerValueChanged(app, event)
            value = app.PSSpinner.Value;
            P.resp.PS = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PSSpinnerLabel = uilabel(app.UIFigure);
            app.PSSpinnerLabel.Text = 'PS';
            app.PSSpinner = uispinner(app.UIFigure);
            app.PSSpinner.ValueChangedFcn = createCallbackFcn(app, @PSSpinnerValueChanged, true);
            app.PEEPSpinnerLabel = uilabel(app.UIFigure);
            app.PEEPSpinnerLabel.Text = 'PEEP';
            app.PEEPSpinner = uispinner(app.UIFigure);
            app.PEEPSpinner.ValueChangedFcn = createCallbackFcn(app, @PEEPSpinnerValueChanged, true);
            app.RRSpinnerLabel = uilabel(app.UIFigure);
            app.RRSpinnerLabel.Text = 'RR';
            app.RRSpinner = uispinner(app.UIFigure);
            app.RRSpinner.ValueChangedFcn = createCallbackFcn(app, @RRSpinnerValueChanged, true);
            app.TCTSpinnerLabel = uilabel(app.UIFigure);
            app.TCTSpinnerLabel.Text = 'TCT';
            app.TCTSpinner = uispinner(app.UIFigure);
            app.TCTSpinner.ValueChangedFcn = createCallbackFcn(app, @TCTSpinnerValueChanged, true);
            app.VentSettingsLabel = uilabel(app.UIFigure);
            app.VentSettingsLabel.Text = 'Vent Settings';
            app.TiSpinnerLabel = uilabel(app.UIFigure);
            app.TiSpinnerLabel.Text = 'Ti';
            app.TiSpinner = uispinner(app.UIFigure);
            app.TiSpinner.ValueChangedFcn = createCallbackFcn(app, @TiSpinnerValueChanged, true);
            app.TeSpinnerLabel = uilabel(app.UIFigure);
            app.TeSpinnerLabel.Text = 'Te';
            app.TeSpinner = uispinner(app.UIFigure);
            app.TeSpinner.ValueChangedFcn = createCallbackFcn(app, @TeSpinnerValueChanged, true);
            app.TriseSpinnerLabel = uilabel(app.UIFigure);
            app.TriseSpinnerLabel.Text = 'Trise';
            app.TriseSpinner = uispinner(app.UIFigure);
            app.TriseSpinner.ValueChangedFcn = createCallbackFcn(app, @TriseSpinnerValueChanged, true);
            app.PSTriggerSpinnerLabel = uilabel(app.UIFigure);
            app.PSTriggerSpinnerLabel.Text = 'PSTrigger';
            app.PSTriggerSpinner = uispinner(app.UIFigure);
            app.PSTriggerSpinner.ValueChangedFcn = createCallbackFcn(app, @PSTriggerSpinnerValueChanged, true);
            app.PmusSettingsLabel = uilabel(app.UIFigure);
            app.PmusSettingsLabel.Text = 'Pmus Settings';
            app.PmusTiSpinnerLabel = uilabel(app.UIFigure);
            app.PmusTiSpinnerLabel.Text = 'PmusTi';
            app.PmusTiSpinner = uispinner(app.UIFigure);
            app.PmusTiSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusTiSpinnerValueChanged, true);
            app.PmusTeSpinnerLabel = uilabel(app.UIFigure);
            app.PmusTeSpinnerLabel.Text = 'PmusTe';
            app.PmusTeSpinner = uispinner(app.UIFigure);
            app.PmusTeSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusTeSpinnerValueChanged, true);
            app.PmusPauseSpinnerLabel = uilabel(app.UIFigure);
            app.PmusPauseSpinnerLabel.Text = 'PmusPause';
            app.PmusPauseSpinner = uispinner(app.UIFigure);
            app.PmusPauseSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusPauseSpinnerValueChanged, true);
            app.PmusSetSpinnerLabel = uilabel(app.UIFigure);
            app.PmusSetSpinnerLabel.Text = 'PmusSet';
            app.PmusSetSpinner = uispinner(app.UIFigure);
            app.PmusSetSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusSetSpinnerValueChanged, true);
            app.PmusExpLgthSpinnerLabel = uilabel(app.UIFigure);
            app.PmusExpLgthSpinnerLabel.Text = 'PmusExpLgth';
            app.PmusExpLgthSpinner = uispinner(app.UIFigure);
            app.PmusExpLgthSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusExpLgthSpinnerValueChanged, true);
            app.PmusCycleSpinnerLabel = uilabel(app.UIFigure);
            app.PmusCycleSpinnerLabel.Text = 'PmusCycle';
            app.PmusCycleSpinner = uispinner(app.UIFigure);
            app.PmusCycleSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusCycleSpinnerValueChanged, true);
            app.LungPropertiesLabel = uilabel(app.UIFigure);
            app.LungPropertiesLabel.Text = 'Lung Properties';
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.CalvSpinnerLabel = uilabel(app.UIFigure);
            app.CalvSpinnerLabel.Text = 'Calv';
            app.CalvSpinner = uispinner(app.UIFigure);
            app.CalvSpinner.ValueChangedFcn = createCallbackFcn(app, @CalvSpinnerValueChanged, true);
            app.CrsSpinnerLabel = uilabel(app.UIFigure);
            app.CrsSpinnerLabel.Text = 'Crs';
            app.CrsSpinner = uispinner(app.UIFigure);
            app.CrsSpinner.ValueChangedFcn = createCallbackFcn(app, @CrsSpinnerValueChanged, true);
            app.CLSpinnerLabel = uilabel(app.UIFigure);
            app.CLSpinnerLabel.Text = 'CL';
            app.CLSpinner = uispinner(app.UIFigure);
            app.CLSpinner.ValueChangedFcn = createCallbackFcn(app, @CLSpinnerValueChanged, true);
            app.SimulationParametersLabel = uilabel(app.UIFigure);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.dtSpinnerLabel = uilabel(app.UIFigure);
            app.dtSpinnerLabel.Text = 'dt';
            app.dtSpinner = uispinner(app.UIFigure);
            app.dtSpinner.ValueChangedFcn = createCallbackFcn(app, @dtSpinnerValueChanged, true);
            app.sim_lgthSpinnerLabel = uilabel(app.UIFigure);
            app.sim_lgthSpinnerLabel.Text = 'sim_lgth';
            app.sim_lgthSpinner = uispinner(app.UIFigure);
            app.sim_lgthSpinner.ValueChangedFcn = createCallbackFcn(app, @sim_lgthSpinnerValueChanged, true);
            app.TdeflateSpinnerLabel = uilabel(app.UIFigure);
            app.TdeflateSpinnerLabel.Text = 'Tdeflate';
            app.TdeflateSpinner = uispinner(app.UIFigure);
            app.TdeflateSpinner.ValueChangedFcn = createCallbackFcn(app, @TdeflateSpinnerValueChanged, true);
            app.InitialValuesLabel = uilabel(app.UIFigure);
            app.InitialValuesLabel.Text = 'Initial Values';
            app.V0SpinnerLabel = uilabel(app.UIFigure);
            app.V0SpinnerLabel.Text = 'V0';
            app.V0Spinner = uispinner(app.UIFigure);
            app.V0Spinner.ValueChangedFcn = createCallbackFcn(app, @V0SpinnerValueChanged, true);
            app.Palv0SpinnerLabel = uilabel(app.UIFigure);
            app.Palv0SpinnerLabel.Text = 'Palv0';
            app.Palv0Spinner = uispinner(app.UIFigure);
            app.Palv0Spinner.ValueChangedFcn = createCallbackFcn(app, @Palv0SpinnerValueChanged, true);
            app.Pao0SpinnerLabel = uilabel(app.UIFigure);
            app.Pao0SpinnerLabel.Text = 'Pao0';
            app.Pao0Spinner = uispinner(app.UIFigure);
            app.Pao0Spinner.ValueChangedFcn = createCallbackFcn(app, @Pao0SpinnerValueChanged, true);
            app.Flow0SpinnerLabel = uilabel(app.UIFigure);
            app.Flow0SpinnerLabel.Text = 'Flow0';
            app.Flow0Spinner = uispinner(app.UIFigure);
            app.Flow0Spinner.ValueChangedFcn = createCallbackFcn(app, @Flow0SpinnerValueChanged, true);
            app.Ppl0SpinnerLabel = uilabel(app.UIFigure);
            app.Ppl0SpinnerLabel.Text = 'Ppl0';
            app.Ppl0Spinner = uispinner(app.UIFigure);
            app.Ppl0Spinner.ValueChangedFcn = createCallbackFcn(app, @Ppl0SpinnerValueChanged, true);
            app.CreatePButton = uibutton(app.UIFigure, 'push');
            app.CreatePButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePButtonPushed, true);
            app.CreatePButton.Text = 'Create P';
            app.WelcomeUserPleaseTuneParametersBeforeSimulationStartLabel = uilabel(app.UIFigure);
            app.WelcomeUserPleaseTuneParametersBeforeSimulationStartLabel.Text = {'Welcome <User> '; 'Please Tune Parameters Before Simulation Start '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parameter_Settings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end