classdef Plot_View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ClearButton          matlab.ui.control.Button
        SetParametersButton  matlab.ui.control.Button
        PlotThatStuffButton  matlab.ui.control.Button
        PplPlot              matlab.ui.control.UIAxes
        FlowPlot             matlab.ui.control.UIAxes
        PmusPlot             matlab.ui.control.UIAxes
        PaoPlot              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Parameter_Settings % Description
    end
    
    methods (Access = public)
        
        function UpdatePlot(app)
            app.SetParametersButton.Enable = "on";
            global P
            P = app.Parameter_Settings.P;
            assignin('base','P',P);
            disp(num2str(P.resp.PEEP))
            
            
            Respiratory_Modelfn_PS(0,0,4)
            plot(app.PaoPlot,P.resp.Pao,'Color','r','LineWidth',2);
            plot(app.PmusPlot,P.resp.Pmus,'Color','r','LineWidth',2);
            plot(app.FlowPlot,P.resp.flow,'Color','r','LineWidth',2);
            plot(app.PplPlot,P.resp.Ppl,'Color','r','LineWidth',2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotThatStuffButton
        function PlotThatStuffButtonPushed(app, event)
            UpdatePlot(app)
            
            
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            app.SetParametersButton.Enable = "off";


            app.Parameter_Settings = Parameter_Settings();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clear all; clc; close all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PaoPlot = uiaxes(app.UIFigure);
            title(app.PaoPlot, 'Pao')
            xlabel(app.PaoPlot, 'Time [s]')
            ylabel(app.PaoPlot, '[cmH2O]')
            zlabel(app.PaoPlot, 'Z')
            app.PmusPlot = uiaxes(app.UIFigure);
            title(app.PmusPlot, 'Pmus')
            xlabel(app.PmusPlot, 'Time [s]')
            ylabel(app.PmusPlot, '[cmH2O]')
            zlabel(app.PmusPlot, 'Z')
            app.FlowPlot = uiaxes(app.UIFigure);
            title(app.FlowPlot, 'Flow')
            xlabel(app.FlowPlot, 'Time [s]')
            ylabel(app.FlowPlot, 'L/min')
            zlabel(app.FlowPlot, 'Z')
            app.FlowPlot.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.PplPlot = uiaxes(app.UIFigure);
            title(app.PplPlot, 'Ppl')
            xlabel(app.PplPlot, 'Time [s]')
            ylabel(app.PplPlot, '[cmH2O]')
            zlabel(app.PplPlot, 'Z')
            app.PlotThatStuffButton = uibutton(app.UIFigure, 'push');
            app.PlotThatStuffButton.ButtonPushedFcn = createCallbackFcn(app, @PlotThatStuffButtonPushed, true);
            app.PlotThatStuffButton.Text = 'Plot That Stuff';
            app.SetParametersButton = uibutton(app.UIFigure, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_View

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end